classdef nethealth < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure              matlab.ui.Figure
    GridLayout            matlab.ui.container.GridLayout
    LeftPanel             matlab.ui.container.Panel
    HostEditFieldLabel    matlab.ui.control.Label
    HostEditField         matlab.ui.control.EditField
    CountLabel            matlab.ui.control.Label
    CountText             matlab.ui.control.Label
    LostLabel             matlab.ui.control.Label
    LostText              matlab.ui.control.Label
    RateHzEditFieldLabel  matlab.ui.control.Label
    RateHzEditField       matlab.ui.control.NumericEditField
    NetHealthLabel        matlab.ui.control.Label
    RunSwitchLabel        matlab.ui.control.Label
    RunSwitch             matlab.ui.control.RockerSwitch
    RightPanel            matlab.ui.container.Panel
    UIAxes                matlab.ui.control.UIAxes
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
  end

  
  properties (Access = private)
    pingTimer timer % timer for ping operations
    pingCount % number of pings
    pingSuccess % number of successes
  end
  
  methods (Access = private)
    
      function handlePing(app, ~, ~)
        [succ, ~] = ping('vps.birge.us');
        app.pingCount = app.pingCount + 1;
        if succ
            app.pingSuccess = app.pingSuccess + 1;
        end
        app.CountText.Text = num2str(app.pingCount);
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      app.pingCount = 0;
      app.pingSuccess = 0;
      app.pingTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Default period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.handlePing);    % Specify callback function
    end

    % Value changed function: RunSwitch
    function RunSwitchValueChanged(app, event)
      value = app.RunSwitch.Value;
      if strcmp(value,'On')
          if strcmp(app.pingTimer.Running, 'off')
               start(app.pingTimer);
          end
      else
          stop(app.pingTimer);
      end
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'UI Figure';
      app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.GridLayout = uigridlayout(app.UIFigure);
      app.LeftPanel = uipanel(app.GridLayout);
      app.HostEditFieldLabel = uilabel(app.LeftPanel);
      app.HostEditFieldLabel.Text = 'Host';
      app.HostEditField = uieditfield(app.LeftPanel, 'text');
      app.HostEditField.Value = 'vps.birge.us';
      app.CountLabel = uilabel(app.LeftPanel);
      app.CountLabel.Text = 'Count: ';
      app.CountText = uilabel(app.LeftPanel);
      app.CountText.Text = '0';
      app.LostLabel = uilabel(app.LeftPanel);
      app.LostLabel.Text = 'Lost (%):';
      app.LostText = uilabel(app.LeftPanel);
      app.LostText.Text = '0';
      app.RateHzEditFieldLabel = uilabel(app.LeftPanel);
      app.RateHzEditFieldLabel.Text = 'Rate (Hz)';
      app.RateHzEditField = uieditfield(app.LeftPanel, 'numeric');
      app.RateHzEditField.Value = 1;
      app.NetHealthLabel = uilabel(app.LeftPanel);
      app.NetHealthLabel.Text = 'NetHealth';
      app.RunSwitchLabel = uilabel(app.LeftPanel);
      app.RunSwitchLabel.Text = 'Run';
      app.RunSwitch = uiswitch(app.LeftPanel, 'rocker');
      app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
      app.RightPanel = uipanel(app.GridLayout);
      app.UIAxes = uiaxes(app.RightPanel);
      title(app.UIAxes, 'Title')
      xlabel(app.UIAxes, 'X')
      ylabel(app.UIAxes, 'Y')
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = nethealth

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end